classdef ribbonAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreliminaryViewingTab           matlab.ui.container.Tab
        ShowCirclesButton               matlab.ui.control.StateButton
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes14                        matlab.ui.control.UIAxes
        UIAxes15                        matlab.ui.control.UIAxes
        SetCirclesdoNOTexitthiswindowButton  matlab.ui.control.Button
        UseCirclesButton                matlab.ui.control.Button
        AfterAnalysisTab                matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        CheckThatDataDropDownLabel      matlab.ui.control.Label
        CheckThatDataDropDown           matlab.ui.control.DropDown
        DGraphTab                       matlab.ui.container.Tab
        UIAxes16                        matlab.ui.control.UIAxes
        Graph3DDropDownLabel            matlab.ui.control.Label
        Graph3DDropDown                 matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        BackgroundDropDown              matlab.ui.control.DropDown
        NoOverlapTab                    matlab.ui.container.Tab
        UIAxes17                        matlab.ui.control.UIAxes
        GraphDropDownLabel              matlab.ui.control.Label
        GraphDropDown                   matlab.ui.control.DropDown
        UpdateRibbonsButton             matlab.ui.control.Button
        RepresentativeTab               matlab.ui.container.Tab
        UIAxes18                        matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        ThresholdNucleusEditFieldLabel  matlab.ui.control.Label
        ThresholdNucleusEditField       matlab.ui.control.NumericEditField
        PresynapticThresholdEditFieldLabel  matlab.ui.control.Label
        PresynapticThresholdEditField   matlab.ui.control.NumericEditField
        PostsynapticThresholdEditFieldLabel  matlab.ui.control.Label
        PostsynapticThresholdEditField  matlab.ui.control.NumericEditField
        NucleusminRangeEditFieldLabel   matlab.ui.control.Label
        NucleusminRangeEditField        matlab.ui.control.NumericEditField
        NucleusmaxRangeEditFieldLabel   matlab.ui.control.Label
        NucleusmaxRangeEditField        matlab.ui.control.NumericEditField
        RibbonminRangeEditFieldLabel    matlab.ui.control.Label
        RibbonminRangeEditField         matlab.ui.control.NumericEditField
        RibbonmaxRangeEditFieldLabel    matlab.ui.control.Label
        RibbonmaxRangeEditField         matlab.ui.control.NumericEditField
        NucleusChannelEditFieldLabel    matlab.ui.control.Label
        NucleusChannelEditField         matlab.ui.control.NumericEditField
        PresynapticChannelEditFieldLabel  matlab.ui.control.Label
        PresynapticChannelEditField     matlab.ui.control.NumericEditField
        PostsynapticChannelEditFieldLabel  matlab.ui.control.Label
        PostsynapticChannelEditField    matlab.ui.control.NumericEditField
        LoadDataButton                  matlab.ui.control.Button
        LookatSliceSpinnerLabel         matlab.ui.control.Label
        LookatSliceSpinner              matlab.ui.control.Spinner
        NucleusSensitivityEditFieldLabel  matlab.ui.control.Label
        NucleusSensitivityEditField     matlab.ui.control.NumericEditField
        Graph1sliceButton               matlab.ui.control.Button
        LastChannelEditFieldLabel       matlab.ui.control.Label
        LastChannelEditField            matlab.ui.control.NumericEditField
        NucleusMedianRangeEditFieldLabel  matlab.ui.control.Label
        NucleusMedianRangeEditField     matlab.ui.control.NumericEditField
        NucleusRadiusMaxEditFieldLabel  matlab.ui.control.Label
        NucleusRadiusMaxEditField       matlab.ui.control.EditField
        RibbonRadiusEditFieldLabel      matlab.ui.control.Label
        RibbonRadiusEditField           matlab.ui.control.EditField
        RibbonMedianRangeEditFieldLabel  matlab.ui.control.Label
        RibbonMedianRangeEditField      matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel           matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        MinimumRibbonSizeEditFieldLabel  matlab.ui.control.Label
        MinimumRibbonSizeEditField      matlab.ui.control.NumericEditField
        LookAtChannelDropDownLabel      matlab.ui.control.Label
        LookAtChannelDropDown           matlab.ui.control.DropDown
        MoreSettingsTab                 matlab.ui.container.Tab
        FirstSliceEditFieldLabel        matlab.ui.control.Label
        FirstSliceEditField             matlab.ui.control.NumericEditField
        SlicestoskipattheendEditFieldLabel  matlab.ui.control.Label
        SlicestoskipattheendEditField   matlab.ui.control.NumericEditField
        xrotationEditFieldLabel         matlab.ui.control.Label
        xrotationEditField              matlab.ui.control.NumericEditField
        yrotationEditFieldLabel         matlab.ui.control.Label
        yrotationEditField              matlab.ui.control.NumericEditField
        loaddatafromworkspaceButton     matlab.ui.control.Button
        UpdatestartingInfoButton        matlab.ui.control.Button
        GraphSliceSpinnerLabel          matlab.ui.control.Label
        GraphSliceSpinner               matlab.ui.control.Spinner
        SaveToEditFieldLabel            matlab.ui.control.Label
        SaveToEditField                 matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        newCenters;
        mu;
        ribbon;
        data;
        sliceNumber;
        dataFile;
        startValue;
        stopValue;
        xRot;
        yRot;
        thresholdN;
        thresholdR1;
        thresholdR2;
        range1;
        range2;
        nSlice;
        r1Slice;
        r2Slice;
        filteredData;
        
        plainData
        voxel
        dimensions
        channel
        minMax
        allData
        S1
        S2
        P13
        P14
        P15
        P13Original
        P13BrightnessAdjusted
        
        NAssociated
        RAssociated
        
        allFiltered
        NCenters
        NRadii
        presynapticPositions
        postsynapticPositions
        
        discardedN
        discardedRPre
        discardedRPost
        
        S3
        S4
        S5
        S6
        S7
        S8
        P4
        P5
        P6
        graphPre
        graphPost
        graphNuc
        MLFigure
        
        channelArray
        Scat3D
        
        
        R1Scat
        R2Scat


    end
    
    methods (Access = private)
        
        function graphOne(app)
            if app.LookAtChannelDropDown.Value=='1'
                
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingNucleus(app.plainData(:,:,app.LookatSliceSpinner.Value,app.NucleusChannelEditField.Value),app.ThresholdNucleusEditField.Value,[app.NucleusMedianRangeEditField.Value,app.NucleusMedianRangeEditField.Value], ...
                    [app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[str2double(app.NucleusRadiusMaxEditField.Value)-40,str2double(app.NucleusRadiusMaxEditField.Value)] ...
                    ,app.NucleusSensitivityEditField.Value,app.UIAxes13,app.UIAxes14,app.UIAxes15,app.minMax(1,1),app.minMax(1,2));
                
                
                app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);
                
            elseif app.LookAtChannelDropDown.Value=='2'
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.LookatSliceSpinner.Value,app.PresynapticChannelEditField.Value),[app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value], ...
                    app.PresynapticThresholdEditField.Value,app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value] ...
                    ,str2double(app.RibbonRadiusEditField.Value),app.minMax(2,1),app.minMax(2,2));
                
                app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);

            elseif app.LookAtChannelDropDown.Value=='3'
                
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.LookatSliceSpinner.Value,app.PostsynapticChannelEditField.Value),[app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value], ...
                    app.PostsynapticThresholdEditField.Value,app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value] ...
                    ,str2double(app.RibbonRadiusEditField.Value),app.minMax(3,1),app.minMax(3,2));
                
                    app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);

            else 
                
            end
        end
        
        function final=contrastFunc(app,changingValue,brightnessAdjusted)
            final=imadjust(brightnessAdjusted,[changingValue,1-changingValue]);
            
        end
        
        function [brightnessAdjusted,finalData]=brightnessFunc(app,changingValue,original)
            brightnessAdjusted=min(original+changingValue,1);
            %app.P13.CData=app.P13BrightnessAdjusted;
            finalData=contrastFunc(app,app.ContrastSlider.Value,brightnessAdjusted);
        end
        
        function showCircles(app,value)
            
            if isgraphics(app.S1,1)
                markerType=["o","none"];
                app.S1.Marker=markerType(value+1);
                app.S2.Marker=markerType(value+1);
            end
        end
        
        function graphFinal(app,slice,channel)
            
            
            [Radii,Centers,DCircles,DRadii]=findStats(app,channel,slice);
            
            
            app.channelArray=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];
            
            [~,Data]=brightnessFunc(app,app.BrightnessSlider.Value,app.plainData(:,:,slice,app.channelArray(channel))/(max(app.plainData(:,:,slice,app.channelArray(channel)),[],'all')));
            Filtered=app.filteredData(:,:,slice,app.channelArray(channel));
            
            
            if size(DCircles,1)>0
                [app.S3,app.S4,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            else
                [~,~,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            end
           
            
        end
        
        function associatedChanged(app)
            set(app.NAssociated,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
            for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
                end
            end
            if app.GraphDropDown.Value==1
                set(app.NAssociated,'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',1,'FaceAlpha',1);
                end
                end
            else
                set(app.NAssociated(app.GraphDropDown.Value-1),'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                    set(app.RAssociated(b).associated(app.GraphDropDown.Value-1).gobject,'AlphaData',1,'FaceAlpha',1);
                end
            end


        end
        
        function associatedRun(app)
            A=[];
            for i = 1:size(app.NAssociated,1)
                A=[A;sprintf('Associated set # %i',i)];
            end
            
            app.GraphDropDown.Items=["All Associated Nuclei";A];
            app.GraphDropDown.ItemsData=[1:i+1];
        end
        
        function toGraphML(app)
           
           app.MLFigure= graphMachineLearning(app.P13,app.S1);
           
        end


        function updateT45(app)
            [app.NAssociated,app.RAssociated]=associateNR(app.ribbon,app.newCenters,app.mu,app.voxel,app.UIAxes17,str2double(app.RibbonRadiusEditField.Value));
            app.UIAxes17.XLabel.String='X(micron)';
            app.UIAxes17.YLabel.String='Y(micron)';
            app.UIAxes17.ZLabel.String='Z(micron)';
            associatedRun(app);
            [app.R1Scat,app.R2Scat]=representNAndR(app.NAssociated,app.RAssociated,app.UIAxes18);
            app.UIAxes18.XLabel.String='X(micron)';
            app.UIAxes18.YLabel.String='Y(micron)';
            app.UIAxes18.ZLabel.String='Z(micron)';
            app.channelArray=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilenameEditFieldValueChanged(app, event)
         
        end

        % Button pushed function: UpdatestartingInfoButton
        function UpdatestartingInfoButtonPushed(app, event)
           
                    app.channelArray=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];


            [app.filteredData,app.NCenters,app.NRadii,app.presynapticPositions,app.postsynapticPositions,app.discardedN,app.discardedRPre,app.discardedRPost,app.ribbon]=analyzeData( ...
                app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value,[app.ThresholdNucleusEditField.Value, ...
                app.PresynapticThresholdEditField.Value,app.PostsynapticThresholdEditField.Value,.1],[app.NucleusMedianRangeEditField.Value,app.NucleusMedianRangeEditField.Value;app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value;app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value;6,6], ...
                app.FirstSliceEditField.Value,app.SlicestoskipattheendEditField.Value,app.NucleusSensitivityEditField.Value,[app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value], ...
                [str2double(app.NucleusRadiusMaxEditField.Value)-40,str2double(app.NucleusRadiusMaxEditField.Value)],app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,app.voxel,app.dimensions,app.plainData);
            
                app.newCenters=app.NCenters;
                app.mu=app.NRadii;


        end

        % Value changed function: xrotationEditField
        function xrotationEditFieldValueChanged(app, event)


            value = app.xrotationEditField.Value;
            app.xRot=value;
        end

        % Value changed function: yrotationEditField
        function yrotationEditFieldValueChanged(app, event)
            value = app.yrotationEditField.Value;
            app.yRot=value;
        end

        % Value changed function: ThresholdNucleusEditField
        function ThresholdNucleusEditFieldValueChanged(app, event)
            value = app.ThresholdNucleusEditField.Value;
            app.thresholdN=value;
        end

        % Value changed function: PresynapticThresholdEditField
        function PresynapticThresholdEditFieldValueChanged(app, event)
            value = app.PresynapticThresholdEditField.Value;
            app.thresholdR1=value;
        end

        % Value changed function: PostsynapticThresholdEditField
        function PostsynapticThresholdEditFieldValueChanged(app, event)
            value = app.PostsynapticThresholdEditField.Value;
            app.thresholdR2=value;
        end

        % Value changed function: FirstSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)
            value = app.FirstSliceEditField.Value;
            app.startValue=value;
        end

        % Value changed function: SlicestoskipattheendEditField
        function SlicestoskipattheendEditFieldValueChanged(app, event)
            value = app.SlicestoskipattheendEditField.Value;
            app.stopValue=value;
        end

        % Value changed function: RibbonminRangeEditField
        function RibbonminRangeEditFieldValueChanged(app, event)
            value = app.RibbonminRangeEditField.Value;
            app.range2(1)=value;
        end

        % Value changed function: NucleusminRangeEditField
        function NucleusminRangeEditFieldValueChanged(app, event)
            value = app.NucleusminRangeEditField.Value;
            app.range1(1)=value;
        end

        % Value changed function: NucleusmaxRangeEditField
        function NucleusmaxRangeEditFieldValueChanged(app, event)
            value = app.NucleusmaxRangeEditField.Value;
            app.range1(2)=value;
        end

        % Value changed function: RibbonmaxRangeEditField
        function RibbonmaxRangeEditFieldValueChanged(app, event)
            value = app.RibbonmaxRangeEditField.Value;
            app.range2(2)=value;
        end

        % Value changed function: PostsynapticChannelEditField
        function PostsynapticChannelEditFieldValueChanged(app, event)
            value = app.PostsynapticChannelEditField.Value;
            app.r1Slice=value;
        end

        % Value changed function: NucleusChannelEditField
        function NucleusChannelEditFieldValueChanged(app, event)
            value = app.NucleusChannelEditField.Value;
            app.nSlice=value;
        end

        % Value changed function: PresynapticChannelEditField
        function PresynapticChannelEditFieldValueChanged(app, event)
            value = app.PresynapticChannelEditField.Value;
            app.r2Slice=value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            
            value = app.FileNameEditField.Value;
            app.dataFile=value;
        end

        % Button pushed function: loaddatafromworkspaceButton
        function loaddatafromworkspaceButtonPushed(app, event)
            %load ('loadData.m', 'newCenters', 'mu', 'ribbon', 'data');
            app.NCenters=evalin('base','newCenters');
            app.NRadii=evalin('base','mu');
            app.plainData=evalin('base','plainData');
            app.filteredData=evalin('base','allFiltered');
            %dropDownMatrix=[1:round((app.SlicestoskipattheendEditField.Value+1-app.FirstSliceEditField.Value)/4)];
%             app.presynapticPositions=evalin('base','sendPre');
%             app.postsynapticPositions=evalin('base','sendPost');
            app.voxel=evalin('base','voxel');
            app.dimensions=evalin('base','dimensions');
            app.minMax=evalin('base','minMax');
            app.presynapticPositions=evalin('base','sendPre');
            app.postsynapticPositions=evalin('base','sendPost');
            app.discardedN=evalin('base','discardedN');
            app.discardedRPre=evalin('base','discardedRPre');
            app.discardedRPost=evalin('base','discardedRPost');
            app.ribbon=evalin('base','ribbon');
            app.newCenters=evalin('base','newCenters');
            app.mu=evalin('base','mu');


            updateT45(app);

            
        end

        % Callback function
        function GraphSliceDropDownValueChanged(app, event)
            value = app.GraphSliceDropDown.Value;
             
        end

        % Callback function
        function GraphSliceEditFieldValueChanged(app, event)
            
        end

        % Value changed function: GraphSliceSpinner
        function GraphSliceSpinnerValueChanged(app, event)
            value = app.GraphSliceSpinner.Value;
            channel=str2double(app.LookAtChannelDropDown.Value);
            graphFinal(app,value,channel);
            
%             graphRibbon(app.UIAxes4,app.UIAxes5,app.UIAxes6,app.presynapticPositions(value).points,app.data(:,:,round(value),app.PresynapticChannelEditField.Value),app.filteredData(:,:,round(value),app.PresynapticChannelEditField.Value));
%                         graphRibbon(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.postsynapticPositions(value).points,app.data(:,:,round(value),app.PostsynapticChannelEditField.Value),app.filteredData(:,:,round(value),app.PostsynapticChannelEditField.Value));
% 
% %             
% %             graphDetected(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.newCenters,app.mu,app.ribbon,app.data(:,:,double(round(value)),app.PresynapticChannelEditField.Value),round(value), app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value, ...
% %             app.filteredData(:,:,round(value),app.PresynapticChannelEditField.Value),[app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value],app.FirstSliceEditField.Value,app.presynapticPositions,app.postsynapticPositions);
%             
%             graphNuclei(app.UIAxes10,app.UIAxes11,app.UIAxes12,app.newCenters,app.mu,app.data(:,:,round(value),app.NucleusChannelEditField.Value),round(value),app.filteredData(:,:,round(value)));


        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [app.voxel,app.dimensions,app.minMax,app.plainData]=initialize(app.FileNameEditField.Value,app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value,app.LastChannelEditField.Value);
            
        end

        % Callback function
        function LookatChannelDropDownValueChanged(app, event)
            
        end

        % Button pushed function: Graph1sliceButton
        function Graph1sliceButtonPushed(app, event)

            graphOne(app);
            
            
        end

        % Value changed function: LookatSliceSpinner
        function LookatSliceSpinnerValueChanged(app, event)
            value = app.LookatSliceSpinner.Value;
            
            if value>app.dimensions(3)
                app.LookatSliceSpinner.Value=app.dimensions(3);
            end
            graphOne(app);
        end

        % Value changed function: ShowCirclesButton
        function ShowCirclesButtonValueChanged(app, event)
            value = app.ShowCirclesButton.Value;            
            showCircles(app,value);
            
        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
             changingValue = event.Value;
             app.P13.CData=contrastFunc(app,changingValue,app.P13BrightnessAdjusted);

        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,changingValue,app.P13Original);
        end

        % Callback function
        function Graph3DpixelsButtonPushed(app, event)
            [app.graphPre,app.graphPost,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,str2double(app.RibbonRadiusEditField.Value));
        end

        % Value changed function: Graph3DDropDown
        function Graph3DDropDownValueChanged(app, event)
            value = app.Graph3DDropDown.Value;
            if value=='Pixels'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RibbonRadiusEditField.Value));
                app.UIAxes16.XLabel.String='X(Pixels)';
                app.UIAxes16.YLabel.String='Y(Pixels)';
                app.UIAxes16.ZLabel.String='Z(Slice)';
            elseif value=='Scaled'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,1,str2double(app.RibbonRadiusEditField.Value));
                app.UIAxes16.XLabel.String='X(micron)';
                app.UIAxes16.YLabel.String='Y(micron)';
                app.UIAxes16.ZLabel.String='Z(micron)';
            end

        end

        % Callback function
        function ButtonPushed(app, event)
            app.GraphDropDown.Items=["All Associated Nuclei","another"];
            app.GraphDropDown.ItemsData=[1,2];
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            associatedChanged(app);
        end

        % Button pushed function: 
        % SetCirclesdoNOTexitthiswindowButton
        function SetCirclesdoNOTexitthiswindowButtonPushed(app, event)
            toGraphML(app);
            
        end

        % Button pushed function: UseCirclesButton
        function UseCirclesButtonPushed(app, event)
            if app.LookAtChannelDropDown.Value=='1'
                settingsArrayN=bestFitSettingsN(app.MLFigure,app.plainData(:,:,app.LookatSliceSpinner.Value,str2double(app.LookAtChannelDropDown.Value)),app.ThresholdNucleusEditField.Value,[app.NucleusMedianRangeEditField.Value,app.NucleusMedianRangeEditField.Value], ...
                    [app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[str2double(app.NucleusRadiusMaxEditField.Value)-40,str2double(app.NucleusRadiusMaxEditField.Value)] ...
                    ,app.NucleusSensitivityEditField.Value,app.minMax(2,1),app.minMax(2,2));
            elseif app.LookAtChannelDropDown.Value=='2'
                settingsArrayR1=bestFitSettingsR(app.MLFigure,app.plainData(:,:,app.LookatSliceSpinner.Value,str2double(app.LookAtChannelDropDown.Value)),[app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value], ...
                    app.PresynapticThresholdEditField.Value,app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value] ...
                    ,str2double(app.RibbonRadiusEditField.Value),app.minMax(3,1),app.minMax(3,2))
            elseif app.LookAtChannelDropDown.Value=='3'
                
            end
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            value = app.BackgroundDropDown.Value;
            f=figure;
            a=axes(f);
            
            [A,B,C,~,~]=graph3D(a,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RibbonRadiusEditField.Value));
            
            set(C(:,1),'FaceAlpha',0,'EdgeColor','r','EdgeAlpha',.15);
            set(A(:,1),'FaceAlpha',.4);
            set(B(:,1),'FaceAlpha',.4);
            
            m=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.FirstSliceEditField.Value,app.SlicestoskipattheendEditField.Value,1);
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g});
            

        end

        % Value changed function: CheckThatDataDropDown
        function CheckThatDataDropDownValueChanged(app, event)
            value = app.CheckThatDataDropDown.Value;
            
            f=figure;
            a=axes(f);


            m=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.FirstSliceEditField.Value,app.SlicestoskipattheendEditField.Value,0,app);


            app.Scat3D=gobjects();
            view(a,0,90)
            for slice=1:sum(isgraphics(g))
                [Radii,Centers,~,~]=findStats(app,str2double(app.CheckThatDataDropDown.Value),slice);

                if size(Centers,1)>0
                
                    [app.Scat3D(slice,1)]=graphCircles3D(a,Centers,Radii,slice,'r');
                end
            end
            
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g,app});
            set(f,'WindowButtonDownFcn',{@addRibbon,g,app});
            
            indices=find(isgraphics(app.Scat3D(:,1))==1);
            set(app.Scat3D(indices,1),'ButtonDownFcn',{@removeRibbon,app,g});
        end

        % Button pushed function: UpdateRibbonsButton
        function UpdateRibbonsButtonPushed(app, event)
            updateT45(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fileName=app.SaveToEditField.Value;
            nucleusData=[app.newCenters,app.mu];
            ribbonData=app.ribbon;
            saveData(fileName,nucleusData,ribbonData,app.voxel,app.R1Scat,app.R2Scat,app.NAssociated,app.RAssociated);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreliminaryViewingTab = uitab(app.TabGroup);
            app.PreliminaryViewingTab.Title = 'Preliminary Viewing';
            app.ShowCirclesButton = uibutton(app.PreliminaryViewingTab, 'state');
            app.ShowCirclesButton.ValueChangedFcn = createCallbackFcn(app, @ShowCirclesButtonValueChanged, true);
            app.ShowCirclesButton.Text = 'Show Circles';
            app.ContrastSliderLabel = uilabel(app.PreliminaryViewingTab);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.PreliminaryViewingTab);
            app.ContrastSlider.Limits = [0 0.4];
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.BrightnessSliderLabel = uilabel(app.PreliminaryViewingTab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.PreliminaryViewingTab);
            app.BrightnessSlider.Limits = [0 1];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.UIAxes13 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes13, '')
            xlabel(app.UIAxes13, '')
            ylabel(app.UIAxes13, '')
            app.UIAxes13.XTick = [];
            app.UIAxes13.YTick = [];
            app.UIAxes14 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes14, '')
            xlabel(app.UIAxes14, '')
            ylabel(app.UIAxes14, '')
            app.UIAxes14.XTick = [];
            app.UIAxes14.YTick = [];
            app.UIAxes15 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes15, '')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            app.UIAxes15.XTick = [];
            app.UIAxes15.XTickLabel = {'X'};
            app.UIAxes15.YTick = [];
            app.UIAxes15.YTickLabel = {'Y'};
            app.SetCirclesdoNOTexitthiswindowButton = uibutton(app.PreliminaryViewingTab, 'push');
            app.SetCirclesdoNOTexitthiswindowButton.ButtonPushedFcn = createCallbackFcn(app, @SetCirclesdoNOTexitthiswindowButtonPushed, true);
            app.SetCirclesdoNOTexitthiswindowButton.Text = 'Set Circles(do NOT exit this window)';
            app.UseCirclesButton = uibutton(app.PreliminaryViewingTab, 'push');
            app.UseCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @UseCirclesButtonPushed, true);
            app.UseCirclesButton.Text = 'Use Circles';
            app.AfterAnalysisTab = uitab(app.TabGroup);
            app.AfterAnalysisTab.Title = 'After Analysis';
            app.UIAxes10 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes11 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes11, '')
            xlabel(app.UIAxes11, '')
            ylabel(app.UIAxes11, '')
            app.UIAxes11.XTick = [];
            app.UIAxes11.YTick = [];
            app.UIAxes12 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes12, '')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.CheckThatDataDropDownLabel = uilabel(app.AfterAnalysisTab);
            app.CheckThatDataDropDownLabel.Text = 'Check That Data!';
            app.CheckThatDataDropDown = uidropdown(app.AfterAnalysisTab);
            app.CheckThatDataDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.CheckThatDataDropDown.ItemsData = {'1', '2', '3'};
            app.CheckThatDataDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckThatDataDropDownValueChanged, true);
            app.CheckThatDataDropDown.Value = '1';
            app.DGraphTab = uitab(app.TabGroup);
            app.DGraphTab.Title = '3D Graph';
            app.UIAxes16 = uiaxes(app.DGraphTab);
            title(app.UIAxes16, '3D Graph')
            xlabel(app.UIAxes16, 'X(micron)')
            ylabel(app.UIAxes16, 'Y(micron)')
            app.Graph3DDropDownLabel = uilabel(app.DGraphTab);
            app.Graph3DDropDownLabel.Text = 'Graph 3D';
            app.Graph3DDropDown = uidropdown(app.DGraphTab);
            app.Graph3DDropDown.Items = {'Pixels', 'Scaled', ''};
            app.Graph3DDropDown.ValueChangedFcn = createCallbackFcn(app, @Graph3DDropDownValueChanged, true);
            app.Graph3DDropDown.Value = 'Scaled';
            app.BackgroundDropDownLabel = uilabel(app.DGraphTab);
            app.BackgroundDropDownLabel.Text = 'Background:';
            app.BackgroundDropDown = uidropdown(app.DGraphTab);
            app.BackgroundDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.BackgroundDropDown.ItemsData = {'1', '2', '3'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = '1';
            app.NoOverlapTab = uitab(app.TabGroup);
            app.NoOverlapTab.Title = 'NoOverlap';
            app.UIAxes17 = uiaxes(app.NoOverlapTab);
            title(app.UIAxes17, 'After Removing Overlapping Nuclei')
            xlabel(app.UIAxes17, 'X(micron)')
            ylabel(app.UIAxes17, 'Y(micron)')
            app.GraphDropDownLabel = uilabel(app.NoOverlapTab);
            app.GraphDropDownLabel.Text = 'Graph:';
            app.GraphDropDown = uidropdown(app.NoOverlapTab);
            app.GraphDropDown.Items = {'All Associated Nuclei'};
            app.GraphDropDown.ItemsData = {'1'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = '1';
            app.UpdateRibbonsButton = uibutton(app.NoOverlapTab, 'push');
            app.UpdateRibbonsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRibbonsButtonPushed, true);
            app.UpdateRibbonsButton.Text = 'Update Ribbons';
            app.RepresentativeTab = uitab(app.TabGroup);
            app.RepresentativeTab.Title = 'Representative';
            app.UIAxes18 = uiaxes(app.RepresentativeTab);
            title(app.UIAxes18, 'Representative Nucleus and Ribbon Positions')
            xlabel(app.UIAxes18, 'X(micron)')
            ylabel(app.UIAxes18, 'Y(micron)')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.FileNameEditFieldLabel = uilabel(app.SettingsTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SettingsTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'IB09MidControlLeft2.czi';
            app.ThresholdNucleusEditFieldLabel = uilabel(app.SettingsTab);
            app.ThresholdNucleusEditFieldLabel.Text = 'Threshold(Nucleus)';
            app.ThresholdNucleusEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ThresholdNucleusEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdNucleusEditFieldValueChanged, true);
            app.ThresholdNucleusEditField.Value = 0.08;
            app.PresynapticThresholdEditFieldLabel = uilabel(app.SettingsTab);
            app.PresynapticThresholdEditFieldLabel.Text = 'PresynapticThreshold';
            app.PresynapticThresholdEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PresynapticThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PresynapticThresholdEditFieldValueChanged, true);
            app.PresynapticThresholdEditField.Value = 0.06;
            app.PostsynapticThresholdEditFieldLabel = uilabel(app.SettingsTab);
            app.PostsynapticThresholdEditFieldLabel.Text = 'Postsynaptic Threshold';
            app.PostsynapticThresholdEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PostsynapticThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PostsynapticThresholdEditFieldValueChanged, true);
            app.PostsynapticThresholdEditField.Value = 0.07;
            app.NucleusminRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NucleusminRangeEditFieldLabel.Text = 'Nucleus min. Range';
            app.NucleusminRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NucleusminRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NucleusminRangeEditFieldValueChanged, true);
            app.NucleusminRangeEditField.Value = 1200;
            app.NucleusmaxRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NucleusmaxRangeEditFieldLabel.Text = 'Nucleus max. Range';
            app.NucleusmaxRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NucleusmaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NucleusmaxRangeEditFieldValueChanged, true);
            app.NucleusmaxRangeEditField.Value = 1500;
            app.RibbonminRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RibbonminRangeEditFieldLabel.Text = 'Ribbon min. Range';
            app.RibbonminRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RibbonminRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RibbonminRangeEditFieldValueChanged, true);
            app.RibbonminRangeEditField.Value = 1200;
            app.RibbonmaxRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RibbonmaxRangeEditFieldLabel.Text = 'Ribbon max. Range';
            app.RibbonmaxRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RibbonmaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RibbonmaxRangeEditFieldValueChanged, true);
            app.RibbonmaxRangeEditField.Value = 1700;
            app.NucleusChannelEditFieldLabel = uilabel(app.SettingsTab);
            app.NucleusChannelEditFieldLabel.Text = 'Nucleus Channel';
            app.NucleusChannelEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NucleusChannelEditField.ValueChangedFcn = createCallbackFcn(app, @NucleusChannelEditFieldValueChanged, true);
            app.NucleusChannelEditField.Value = 1;
            app.PresynapticChannelEditFieldLabel = uilabel(app.SettingsTab);
            app.PresynapticChannelEditFieldLabel.Text = 'Presynaptic Channel';
            app.PresynapticChannelEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PresynapticChannelEditField.ValueChangedFcn = createCallbackFcn(app, @PresynapticChannelEditFieldValueChanged, true);
            app.PresynapticChannelEditField.Value = 3;
            app.PostsynapticChannelEditFieldLabel = uilabel(app.SettingsTab);
            app.PostsynapticChannelEditFieldLabel.Text = 'Postsynaptic Channel';
            app.PostsynapticChannelEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PostsynapticChannelEditField.ValueChangedFcn = createCallbackFcn(app, @PostsynapticChannelEditFieldValueChanged, true);
            app.PostsynapticChannelEditField.Value = 2;
            app.LoadDataButton = uibutton(app.SettingsTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LookatSliceSpinnerLabel = uilabel(app.SettingsTab);
            app.LookatSliceSpinnerLabel.Text = 'Look at Slice:';
            app.LookatSliceSpinner = uispinner(app.SettingsTab);
            app.LookatSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @LookatSliceSpinnerValueChanged, true);
            app.LookatSliceSpinner.Value = 10;
            app.NucleusSensitivityEditFieldLabel = uilabel(app.SettingsTab);
            app.NucleusSensitivityEditFieldLabel.Text = 'Nucleus Sensitivity';
            app.NucleusSensitivityEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NucleusSensitivityEditField.Value = 0.972;
            app.Graph1sliceButton = uibutton(app.SettingsTab, 'push');
            app.Graph1sliceButton.ButtonPushedFcn = createCallbackFcn(app, @Graph1sliceButtonPushed, true);
            app.Graph1sliceButton.Text = 'Graph 1 slice';
            app.LastChannelEditFieldLabel = uilabel(app.SettingsTab);
            app.LastChannelEditFieldLabel.Text = 'Last Channel';
            app.LastChannelEditField = uieditfield(app.SettingsTab, 'numeric');
            app.LastChannelEditField.Value = 4;
            app.NucleusMedianRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NucleusMedianRangeEditFieldLabel.Text = 'Nucleus Median Range';
            app.NucleusMedianRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NucleusMedianRangeEditField.Value = 6;
            app.NucleusRadiusMaxEditFieldLabel = uilabel(app.SettingsTab);
            app.NucleusRadiusMaxEditFieldLabel.Text = 'Nucleus Radius Max';
            app.NucleusRadiusMaxEditField = uieditfield(app.SettingsTab, 'text');
            app.NucleusRadiusMaxEditField.Value = '100';
            app.RibbonRadiusEditFieldLabel = uilabel(app.SettingsTab);
            app.RibbonRadiusEditFieldLabel.Text = 'Ribbon Radius';
            app.RibbonRadiusEditField = uieditfield(app.SettingsTab, 'text');
            app.RibbonRadiusEditField.Value = '15';
            app.RibbonMedianRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RibbonMedianRangeEditFieldLabel.Text = 'Ribbon Median Range';
            app.RibbonMedianRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RibbonMedianRangeEditField.Value = 5;
            app.EpsilonEditFieldLabel = uilabel(app.SettingsTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.SettingsTab, 'numeric');
            app.EpsilonEditField.Value = 10;
            app.MinimumRibbonSizeEditFieldLabel = uilabel(app.SettingsTab);
            app.MinimumRibbonSizeEditFieldLabel.Text = 'Minimum Ribbon Size';
            app.MinimumRibbonSizeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.MinimumRibbonSizeEditField.Value = 5;
            app.LookAtChannelDropDownLabel = uilabel(app.SettingsTab);
            app.LookAtChannelDropDownLabel.Text = 'Look At Channel';
            app.LookAtChannelDropDown = uidropdown(app.SettingsTab);
            app.LookAtChannelDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic', 'Extra'};
            app.LookAtChannelDropDown.ItemsData = {'1', '2', '3', '4'};
            app.LookAtChannelDropDown.Value = '2';
            app.MoreSettingsTab = uitab(app.TabGroup2);
            app.MoreSettingsTab.Title = 'More Settings';
            app.FirstSliceEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.FirstSliceEditFieldLabel.Text = 'First Slice';
            app.FirstSliceEditField = uieditfield(app.MoreSettingsTab, 'numeric');
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Value = 1;
            app.SlicestoskipattheendEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.SlicestoskipattheendEditFieldLabel.Text = 'Slices to skip at the end';
            app.SlicestoskipattheendEditField = uieditfield(app.MoreSettingsTab, 'numeric');
            app.SlicestoskipattheendEditField.ValueChangedFcn = createCallbackFcn(app, @SlicestoskipattheendEditFieldValueChanged, true);
            app.SlicestoskipattheendEditField.Value = 30;
            app.xrotationEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.xrotationEditFieldLabel.Text = 'x rotation';
            app.xrotationEditField = uieditfield(app.MoreSettingsTab, 'numeric');
            app.xrotationEditField.ValueChangedFcn = createCallbackFcn(app, @xrotationEditFieldValueChanged, true);
            app.yrotationEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.yrotationEditFieldLabel.Text = 'y rotation';
            app.yrotationEditField = uieditfield(app.MoreSettingsTab, 'numeric');
            app.yrotationEditField.ValueChangedFcn = createCallbackFcn(app, @yrotationEditFieldValueChanged, true);
            app.loaddatafromworkspaceButton = uibutton(app.MoreSettingsTab, 'push');
            app.loaddatafromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatafromworkspaceButtonPushed, true);
            app.loaddatafromworkspaceButton.Text = 'load data(from workspace)';
            app.UpdatestartingInfoButton = uibutton(app.MoreSettingsTab, 'push');
            app.UpdatestartingInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestartingInfoButtonPushed, true);
            app.UpdatestartingInfoButton.Text = 'Update starting Info.';
            app.GraphSliceSpinnerLabel = uilabel(app.MoreSettingsTab);
            app.GraphSliceSpinnerLabel.Text = 'Graph Slice:';
            app.GraphSliceSpinner = uispinner(app.MoreSettingsTab);
            app.GraphSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @GraphSliceSpinnerValueChanged, true);
            app.SaveToEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.SaveToEditFieldLabel.Text = 'Save To:';
            app.SaveToEditField = uieditfield(app.MoreSettingsTab, 'text');
            app.SaveButton = uibutton(app.MoreSettingsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ribbonAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end